classdef DAC_Converter_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        reset_button_3             matlab.ui.control.Button
        SIMULATIONButton           matlab.ui.control.Button
        DACCONVERTERLabel          matlab.ui.control.Label
        Tab2                       matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Pin4Label                  matlab.ui.control.Label
        Pin5Label                  matlab.ui.control.Label
        Pin6Label                  matlab.ui.control.Label
        Pin7Label                  matlab.ui.control.Label
        Switch_0                   matlab.ui.control.RockerSwitch
        Switch_1                   matlab.ui.control.RockerSwitch
        Switch_2                   matlab.ui.control.RockerSwitch
        Switch_3                   matlab.ui.control.RockerSwitch
        Image2_6                   matlab.ui.control.Image
        Image2_5                   matlab.ui.control.Image
        Image2_4                   matlab.ui.control.Image
        Image2_3                   matlab.ui.control.Image
        Image2_2                   matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        home_button                matlab.ui.control.Button
        reset_button               matlab.ui.control.Button
        Spinner                    matlab.ui.control.Spinner
        plot_button                matlab.ui.control.Button
        DropDown_3                 matlab.ui.control.DropDown
        yekseniDropDown            matlab.ui.control.DropDown
        xekseniDropDown            matlab.ui.control.DropDown
        ComPortDropDown            matlab.ui.control.DropDown
        Lamp                       matlab.ui.control.Lamp
        SerialCommunicationSwitch  matlab.ui.control.ToggleSwitch
        SerialCommunicationLabel   matlab.ui.control.Label
        voltageEditField           matlab.ui.control.NumericEditField
        analogEditField            matlab.ui.control.NumericEditField
        add_button                 matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
    end

        properties (Access = public)
        Property % Description
        deger=0;
        table_data=zeros(16,4);
        x_value=[];
        y_value=[];
        analog_data_2=[];
        voltage_data_2=[];

        %% A global definition was made to define the bits.
        binary0 = 0;
        binary1 = 0;
        binary2 = 0;
        binary3 = 0;
        %%
        analog_data=[0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0];
        voltage_data=[0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0];
        %% binary dizisi
        binary_input=[0; 1; 2; 3; 4; 5; 6; 7; 8; 9; 10; 11; 12; 13; 14; 15];
        %% ideal volt
        ideal_volt = [0.00; 0.31; 0.62; 0.93; 1.25; 1.56; 1.87; 2.19; 2.50; 2.81; 3.12; 3.44; 3.75; 4.06; 4.37; 4.69];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: add_button
        function add_buttonPushed(app, event)
            %% Entering values into the table
            i=app.Spinner.Value;
            app.analog_data(i)=app.analogEditField.Value;
            app.voltage_data(i)=app.voltageEditField.Value;
            app.table_data(i,:)= [app.binary_input(i) app.analog_data(i) app.ideal_volt(i) app.voltage_data(i)];
            app.UITable.Data=app.table_data;
            app.Spinner.Value=app.Spinner.Value+1;
            if(app.Spinner.Value>16)
                app.Spinner.Value=1;
            end
        end

        % Button pushed function: plot_button
        function plot_buttonPushed(app, event)
            %% Plotting section
            switch(app.xekseniDropDown.Value)
                case "BINARY INPUT"
                    app.x_value=1;
                case "ANALOG VALUE"
                    app.x_value=2;
                case "IDEAL VOLTAGE"
                    app.x_value=3;
                case "ARDUINO VOLT"
                    app.x_value=4;
            end
            switch(app.yekseniDropDown.Value)
                case "ANALOG VALUE"
                    app.y_value=2;
                case "IDEAL VOLTAGE"
                    app.y_value=3;
                case "ARDUINO VOLT"
                    app.y_value=4;
            end
            switch(app.DropDown_3.Value)
                case "INPUT-OUTPUT"
                    x=app.table_data(:,app.x_value);
                    y=app.table_data(:,app.y_value);

                    stem(app.UIAxes, x, y, 'filled', 'MarkerSize', 10);
                    grid (app.UIAxes,"on")
                case "INL-GRAPGH"
                    x=app.table_data(:,3);
                    y=app.table_data(:,4);
                    INL_deger=x-y;
                    xaxis=1:16;
                    plot(app.UIAxes,xaxis,INL_deger, 'red',LineWidth=2,LineStyle='-.',Marker='n');
                    grid (app.UIAxes,"on")
            end
        end

        % Value changed function: SerialCommunicationSwitch
        function SerialCommunicationSwitchValueChanged(app, event)
            %% Communication
            value = app.SerialCommunicationSwitch.Value;
            app.deger = app.deger + 1;
        
            if (value == "On")
                x = string(app.ComPortDropDown.Value);
                if (app.deger == 1)
                    s = serialport(x, 9600);
                end
        
                app.Lamp.Color = 'g';
                while (value == "On")
                    data = readline(s);
                    app.analogEditField.Value = double(data);
                    app.voltageEditField.Value = double(data) * 5.0 / 1023.0;

                    MSB1= app.binary3*(2^3);
                    MSB2 = app.binary2*(2^2);
                    LSB2 = app.binary1*(2^1);
                    LSB1 = app.binary0;
        
                    dataToSend = LSB1+LSB2+MSB2+MSB1;
                    harf=char(dataToSend+97);
                    fprintf(s, '%c', harf);

                    % Koşul ekleyerek döngüyü bitiririz
                    if (app.SerialCommunicationSwitch.Value == "Off")
                        app.deger = 0;
                        break;
                    end
                end
            else
                app.Lamp.Color = 'r';
            end
        end

        % Button pushed function: home_button
        function home_buttonButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: SIMULATIONButton
        function SIMULATIONButtonPushed(app, event)
             app.TabGroup.SelectedTab=app.Tab2;

        end

        % Callback function
        function Button_2Pushed(app, event)

        end

        % Value changed function: ComPortDropDown
        function ComPortDropDownValueChanged(app, event)
            
        end

        % Callback function
        function analogEditField_2ValueChanged(app, event)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Callback function
        function add_button_2ButtonPushed(app, event)

        end

        % Callback function
        function Spinner_2ValueChanged(app, event)

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
        end

        % Value changed function: voltageEditField
        function voltageEditFieldValueChanged(app, event)
            value = app.voltageEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function plot_button_2ButtonPushed(app, event)

        end

        % Value changed function: xekseniDropDown
        function xekseniDropDownValueChanged(app, event)
            value = app.xekseniDropDown.Value;
            
        end

        % Value changed function: yekseniDropDown
        function yekseniDropDownValueChanged(app, event)
            value = app.yekseniDropDown.Value;
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch(app.DropDown_3.Value)
                case "INPUT-OUTPUT"
                    app.xekseniDropDown.Visible="on";
                    app.yekseniDropDown.Visible="on";
                case "INL-GRAPGH"
                    app.xekseniDropDown.Visible="off";
                    app.yekseniDropDown.Visible="off";
            end 
        end

        % Callback function
        function reset_button_2ButtonPushed(app, event)

        end

        % Callback function
        function home_button_2ButtonPushed(app, event)
                        app.TabGroup.SelectedTab=app.Tab;

        end

        % Button pushed function: reset_button
        function reset_buttonButtonPushed(app, event)
            %% Reset button case
            app.analog_data=[0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0];
            app.voltage_data=[0;0;0;0;0;0;0;0;0;0;0;0;0;0;0;0];
            app.table_data= [app.binary_input app.analog_data app.ideal_volt app.voltage_data];
            app.UITable.Data=app.table_data;
            app.Spinner.Value=1;
            plot(app.UIAxes,0,0);
            app.analogEditField.Value=0;
            app.voltageEditField.Value=0;
        end

        % Value changed function: analogEditField
        function analogEditFieldValueChanged(app, event)
            value = app.analogEditField.Value;
        end

        % Button pushed function: reset_button_3
        function reset_button_3ButtonPushed(app, event)
            msgbox("The interface accessed by the button on the right receives data manually from the user. and automatically prints graphs for analysis. This gui allows the user to analyze the INL and input-output graph. The interface accessed by the button on the right receives data directly from Arduino via serial communication. After serial communication, the user can select the x-y axis of the desired graph and perform analysis by selecting the graph type.","Information","help");
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            %% Binary 3. bit switch 
            value = app.Switch_3.Value;
            if (value == '0')
                app.binary3 = 0;
            elseif (value == '1')
                app.binary3 = 1;
            end
            app.Label.Text=value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            %% Binary 2. bit switch 
            value = app.Switch_2.Value;
            if (value == '0')
                app.binary2 = 0;
            elseif (value == '1')
                app.binary2 = 1;
            end
            app.Label_2.Text=value;
        end

        % Value changed function: Switch_1
        function Switch_1ValueChanged(app, event)
            %% Binary 1. bit switch 
            value = app.Switch_1.Value;
            if (value == '0')
                app.binary1 = 0;
            elseif (value == '1')
                app.binary1 = 1;
            end
            app.Label_3.Text=value;
        end

        % Value changed function: Switch_0
        function Switch_0ValueChanged(app, event)
            %% Binary 0. bit switch 
            value = app.Switch_0.Value;
            if (value == '0')
                app.binary0 = 0;
            elseif (value == '1')
                app.binary0 = 1;
            end
            app.Label_4.Text=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.DACCONVERTERLabel = uilabel(app.GridLayout);
            app.DACCONVERTERLabel.Text = 'DAC CONVERTER';
            app.SIMULATIONButton = uibutton(app.GridLayout, 'push');
            app.SIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATIONButtonPushed, true);
            app.SIMULATIONButton.Icon = fullfile(pathToMLAPP, 'clipart1179524.png');
            app.SIMULATIONButton.Text = 'SIMULATION';
            app.reset_button_3 = uibutton(app.GridLayout, 'push');
            app.reset_button_3.ButtonPushedFcn = createCallbackFcn(app, @reset_button_3ButtonPushed, true);
            app.reset_button_3.Icon = fullfile(pathToMLAPP, '682055.png');
            app.reset_button_3.Text = '';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.GridLayout2 = uigridlayout(app.Tab2);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'BINARY INPUT'; 'ANALOG VALUE'; 'IDEAL VOLT'; 'ARDUINO VOLT'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.add_button = uibutton(app.GridLayout2, 'push');
            app.add_button.ButtonPushedFcn = createCallbackFcn(app, @add_buttonPushed, true);
            app.add_button.Icon = fullfile(pathToMLAPP, 'big-data-icon-512x512-57oppg4j.png');
            app.add_button.Text = {'ADD TO'; 'TABLE'};
            app.analogEditField = uieditfield(app.GridLayout2, 'numeric');
            app.analogEditField.ValueChangedFcn = createCallbackFcn(app, @analogEditFieldValueChanged, true);
            app.voltageEditField = uieditfield(app.GridLayout2, 'numeric');
            app.voltageEditField.ValueChangedFcn = createCallbackFcn(app, @voltageEditFieldValueChanged, true);
            app.SerialCommunicationLabel = uilabel(app.GridLayout2);
            app.SerialCommunicationLabel.Text = {'Serial'; 'Communication:'};
            app.SerialCommunicationSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.SerialCommunicationSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialCommunicationSwitchValueChanged, true);
            app.Lamp = uilamp(app.GridLayout2);
            app.ComPortDropDown = uidropdown(app.GridLayout2);
            app.ComPortDropDown.Items = {'COM1', 'COM10', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.ComPortDropDown.ValueChangedFcn = createCallbackFcn(app, @ComPortDropDownValueChanged, true);
            app.ComPortDropDown.Value = 'COM1';
            app.xekseniDropDown = uidropdown(app.GridLayout2);
            app.xekseniDropDown.Items = {'BINARY INPUT', 'ANALOG VALUE', 'IDEAL VOLTAGE', 'ARDUINO VOLT'};
            app.xekseniDropDown.ValueChangedFcn = createCallbackFcn(app, @xekseniDropDownValueChanged, true);
            app.xekseniDropDown.Value = 'BINARY INPUT';
            app.yekseniDropDown = uidropdown(app.GridLayout2);
            app.yekseniDropDown.Items = {'ANALOG VALUE', 'IDEAL VOLTAGE', 'ARDUINO VOLT'};
            app.yekseniDropDown.ValueChangedFcn = createCallbackFcn(app, @yekseniDropDownValueChanged, true);
            app.yekseniDropDown.Value = 'ANALOG VALUE';
            app.DropDown_3 = uidropdown(app.GridLayout2);
            app.DropDown_3.Items = {'INPUT-OUTPUT', 'INL-GRAPGH'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'INPUT-OUTPUT';
            app.plot_button = uibutton(app.GridLayout2, 'push');
            app.plot_button.ButtonPushedFcn = createCallbackFcn(app, @plot_buttonPushed, true);
            app.plot_button.Icon = fullfile(pathToMLAPP, 'bar-graph-icon-2048x2048-inn5thu4.png');
            app.plot_button.Text = 'PLOT';
            app.Spinner = uispinner(app.GridLayout2);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.reset_button = uibutton(app.GridLayout2, 'push');
            app.reset_button.ButtonPushedFcn = createCallbackFcn(app, @reset_buttonButtonPushed, true);
            app.reset_button.Icon = fullfile(pathToMLAPP, '6680865.png');
            app.reset_button.Text = '';
            app.home_button = uibutton(app.GridLayout2, 'push');
            app.home_button.ButtonPushedFcn = createCallbackFcn(app, @home_buttonButtonPushed, true);
            app.home_button.Icon = fullfile(pathToMLAPP, '1299961.png');
            app.home_button.Text = '';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '1147124.png');
            app.Image2_2 = uiimage(app.GridLayout2);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, '5044316.png');
            app.Image2_3 = uiimage(app.GridLayout2);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, '778668.png');
            app.Image2_4 = uiimage(app.GridLayout2);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-10-17 170251-depositphotos-bgremover.png');
            app.Image2_5 = uiimage(app.GridLayout2);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, '7954782.png');
            app.Image2_6 = uiimage(app.GridLayout2);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, '5127888.png');
            app.Switch_3 = uiswitch(app.GridLayout2, 'rocker');
            app.Switch_3.Items = {'0', '1'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '0';
            app.Switch_2 = uiswitch(app.GridLayout2, 'rocker');
            app.Switch_2.Items = {'0', '1'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '0';
            app.Switch_1 = uiswitch(app.GridLayout2, 'rocker');
            app.Switch_1.Items = {'0', '1'};
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @Switch_1ValueChanged, true);
            app.Switch_1.Value = '0';
            app.Switch_0 = uiswitch(app.GridLayout2, 'rocker');
            app.Switch_0.Items = {'0', '1'};
            app.Switch_0.ValueChangedFcn = createCallbackFcn(app, @Switch_0ValueChanged, true);
            app.Switch_0.Value = '0';
            app.Pin7Label = uilabel(app.GridLayout2);
            app.Pin7Label.Text = {'Pin:'; '7'};
            app.Pin6Label = uilabel(app.GridLayout2);
            app.Pin6Label.Text = {'Pin:'; '6'};
            app.Pin5Label = uilabel(app.GridLayout2);
            app.Pin5Label.Text = {'Pin:'; '5'};
            app.Pin4Label = uilabel(app.GridLayout2);
            app.Pin4Label.Text = {'Pin:'; '4'};
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAC_Converter_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end